classdef curve_fitter < matlab.apps.AppBase
    %CURVE_FITTER curve fitter app
    %   my own curve fitting app

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        CancelButton            matlab.ui.control.Button
        EnterButton             matlab.ui.control.Button
        ParametersSettingPanel  matlab.ui.container.Panel
        LaunchButton            matlab.ui.control.Button
        DropDown                matlab.ui.control.DropDown
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataX % Description
        dataY
        diffY
        unit
    end
    
    methods (Access = private)
        
        function friniplot(app)
            yyaxis left;
            scatter(app.UIAxes,app.dataX,app.dataY);
            hold on;
            ylim1 = [min(app.dataY),max(app.dataY)];
            set(app.UIAxes,'YLim',[2*ylim1(1) - ylim1(2),ylim1(2)]);
            ylabel(['Tool path radius (',app.unit,')']);
            yyaxis right;
            bar(app.UIAxes,app.dataX(1:end - 1),app.diffY);
            ylim2 = [min(app.diffY),max(app.diffY)];
            set(app.UIAxes,'YLim',[ylim2(1),2*ylim2(2) - ylim2(1)]);
            ylabel(['Cutting width (',app.unit,')']);
            % line([0,loopRcsape(end)/(2*pi/maxAngPtDist/rStep)],[0,loopRcsape(end)], ...
            %     'Color',[0.929,0.694,0.1250]);
            set(app.UIAxes,'FontSize',textFontSize,'FontName',textFontType);
            xlabel(app.UIAxes,'Concentric Angle');
            legend(app.UIAxes,'Scatters','Pitch');
        end

        % axes' refresh
        function frplot(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_x, data_y, unit)
            app.dataX = data_x;
            app.dataY = data_y;
            app.diffY = diff(data_y);
            app.unit = unit;

            friniplot(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'', 'CurveFitter', 'polyfit', 'csape', 'csaps', 'spapi', 'Basic Spline'''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Placeholder = 'Fitters';
            app.DropDown.Value = '';
            app.LaunchButton = uibutton(app.GridLayout, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.ParametersSettingPanel = uipanel(app.GridLayout);
            app.ParametersSettingPanel.Title = 'Parameters Setting';
            app.EnterButton = uibutton(app.GridLayout, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curve_fitter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end